classdef AgilentHPLC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Phase1Tab                       matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        Step1Label                      matlab.ui.control.Label
        ChooseDataTypeLabel             matlab.ui.control.Label
        DataType                        matlab.ui.control.DropDown
        Step2Label                      matlab.ui.control.Label
        LoadFilesButton                 matlab.ui.control.Button
        Step7Label                      matlab.ui.control.Label
        SelectPeaksButton               matlab.ui.control.Button
        ListBox                         matlab.ui.control.ListBox
        OptionalStep4aADVANCEDLabel     matlab.ui.control.Label
        ManualEditPeakButton            matlab.ui.control.Button
        Step3HighlightChromatogramFoldertouseforPeakSelectionLabel  matlab.ui.control.Label
        Generate3DVisualizationButton   matlab.ui.control.Button
        Step4Label                      matlab.ui.control.Label
        ChooseTimeIntervalthatRepresentsParentPeakLabel  matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        CloseAppButton                  matlab.ui.control.Button
        ListBox_2                       matlab.ui.control.ListBox
        Step6Label                      matlab.ui.control.Label
        ExportDataButton                matlab.ui.control.Button
        Step5Label                      matlab.ui.control.Label
        GenerateParentFractionButton    matlab.ui.control.Button
        AsymmetricParameterEditFieldLabel  matlab.ui.control.Label
        AsymmetricParameterEditField    matlab.ui.control.NumericEditField
        SmoothnessParameterEditFieldLabel  matlab.ui.control.Label
        SmoothnessParameterEditField    matlab.ui.control.NumericEditField
        OPTIONALStep2aADVANCEDLabel     matlab.ui.control.Label
        Step8Label                      matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        TextArea_2                      matlab.ui.control.TextArea
        AdjustParametersCheckBox        matlab.ui.control.CheckBox
        CloseFiguresButton              matlab.ui.control.Button
        TextArea_3                      matlab.ui.control.TextArea
        TextArea_4                      matlab.ui.control.TextArea
        NoiseButton                     matlab.ui.control.Button
        ApplyCheckBox                   matlab.ui.control.CheckBox
        OPTIONALStep3aADVANCEDLabel     matlab.ui.control.Label
        ListBox_3                       matlab.ui.control.ListBox
        Phase2Tab                       matlab.ui.container.Tab
        UIAxes3                         matlab.ui.control.UIAxes
        UncorrectedPlasmaActivityButton  matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        InputParentFractiontxtpathButton  matlab.ui.control.Button
        EditField_2                     matlab.ui.control.EditField
        EditFieldPMOD                   matlab.ui.control.EditField
        ClearButton                     matlab.ui.control.Button
        GenerateCombinedAnalysisButton  matlab.ui.control.Button
        Step1InputUncorrectedPlasmaBldFilePathsLabel  matlab.ui.control.Label
        Step2InputParentFractionFilePathtxtorbldLabel  matlab.ui.control.Label
        Step3GenerateAnalysisLabel      matlab.ui.control.Label
        ListBox_4                       matlab.ui.control.ListBox
    end

    
    properties (Access = private)
         initializetoolbox;
         RadiotracerdataMap=containers.Map(); 
         UnprocessedRadiotracerdataMap=containers.Map();
         SelectedPeaksMap=containers.Map();
         AUC;
    end
    
    methods (Access = private)
        
        function PreprocessRadiotracer(app)
            app.ShiftTracerDataAboveBaseline();
            app.DecayCorrectRadiotracer(app.DataType.Value);
            app.ApplySmoothingandAsymmetry(app.SmoothnessParameterEditField.Value,app.AsymmetricParameterEditField.Value);
        end
        
        function DecayCorrectRadiotracer(app,typeofisotope)
            if typeofisotope=='F-18'
                app.DecayCorrectF18();
                
            elseif typeofisotope=='C-11'
                app.DecayCorrectC11();
            else
                error('Could not apply proper decay correction to specified radiotracer.');
            end
        end
        
         %---------------------------------------------------------------------
         % Refer to http://www.turkupetcentre.net/petanalysis/decay.html
         % for derivation of correction function to apply 
         
         %Iterate through map and decay correct HPLC data
        function DecayCorrectC11(app)
            lamda=.034;
            mapkeys=keys(app.RadiotracerdataMap);
            for i=1:length(mapkeys)
                chromatogramfile=mapkeys{i};
                tracerdata=app.RadiotracerdataMap(chromatogramfile);
                lamdatimeproduct=lamda.*(tracerdata.time);
                exponentterm=exp(lamdatimeproduct);
                decaycorrectedvalues=(tracerdata.tic.values).*exponentterm;
                tracerdata.tic.values=decaycorrectedvalues;
                app.RadiotracerdataMap(chromatogramfile)=tracerdata;
            end
        end
        
        function DecayCorrectF18(app)
            lamda=.006;
            mapkeys=keys(app.RadiotracerdataMap);
            for i=1:length(mapkeys)
                chromatogramfile=mapkeys{i};
                tracerdata=app.RadiotracerdataMap(chromatogramfile);
                lamdatimeproduct=lamda.*(tracerdata.time);
                exponentterm=exp(lamdatimeproduct);
                decaycorrectedvalues=(tracerdata.tic.values).*exponentterm;
                tracerdata.tic.values=decaycorrectedvalues;
                app.RadiotracerdataMap(chromatogramfile)=tracerdata;
            end
        end
        
        %---------------------------------------------------------------------

        
        function ApplySmoothingandAsymmetry(app,smooth,asymmetry)
        
            mapkeys=keys(app.RadiotracerdataMap);
            for i=1:length(mapkeys)
                chromatogramfile=mapkeys{i};
                tracerdata=app.RadiotracerdataMap(chromatogramfile);
                tracerdata=app.initializetoolbox.smooth(tracerdata,...
                'samples','all',...
                'ions','tic',...
                'smoothness',smooth,...
                'asymmetry',asymmetry);
                app.RadiotracerdataMap(chromatogramfile)=tracerdata;
            end
           
        end
        
        
        function ShiftTracerDataAboveBaseline(app)
            mapkeys=keys(app.RadiotracerdataMap);
            for i=1:length(mapkeys)
                chromatogramfile=mapkeys{i};
                tracerdata=app.RadiotracerdataMap(chromatogramfile);
                translationamount=abs(min(tracerdata.tic.values));
                tracerdata.tic.values=tracerdata.tic.values+translationamount;
                app.RadiotracerdataMap(chromatogramfile)=tracerdata;
            end
        end
        
        function DisplayChromatogramFileNames(app)
            mapkeys=keys(app.RadiotracerdataMap);
            for i=1:length(mapkeys)
                chromatogramfile=mapkeys{i};
                [~,name,~] = fileparts(chromatogramfile);
                app.ListBox.Items{i}=name;
            end
        end
        
        function PlotChromatogramData(app)
            chromatogramfile=app.ListBox.Value;
            isValiddata=contains(chromatogramfile,'Item');
            if ~isValiddata
                unprocesseddata=app.UnprocessedRadiotracerdataMap(chromatogramfile);
                processeddata=app.RadiotracerdataMap(chromatogramfile);
                
                cla(app.UIAxes,'reset');
                plot(app.UIAxes,unprocesseddata.time*60,unprocesseddata.tic.values,'Color','r');
                hold(app.UIAxes);
                plot(app.UIAxes,processeddata.time*60,processeddata.tic.values,'Color','b');
                chromatogramendtimeinsecs=round(processeddata.time(end))*60;
                app.UIAxes.XLim = [0 chromatogramendtimeinsecs];
                
                underline = strfind(chromatogramfile,'_');
                chromatogramfile(underline)= '-'; %change for graphic reasons
                title(app.UIAxes, chromatogramfile);
                legend(app.UIAxes,{'Unprocessed','Decay Corrected, Smoothed'},'Location','Best')
            end
        end
        
        function ApplyNoiseReduction(app)
            chromatogramfile=app.ListBox.Value;
            isValiddata=contains(chromatogramfile,'Item');
            if ~isValiddata
                %Load data, and find time interval that is defined noise
                processeddata=app.RadiotracerdataMap(chromatogramfile);
                noiseinterval=app.SelectInterval(processeddata);
                if ~isempty(noiseinterval)
                    app.SubtractMeanNoisefromRadioactivityData(processeddata,noiseinterval)
                else
                    f = warningdlg('User did not select noise interval. Noise reduction not applied.');
                end
            end
            
        end
        
        function noiseintervalarray=SelectInterval(app,processeddata)
            figure(1);
            plot(processeddata.time*60,processeddata.tic.values)
            xlabel('Time (secs)');
            ylabel('Radioactivity');
            try
                [noiseintervalarray,~] = getpts();
            catch
                noiseintervalarray=[];
                return
            end
            close(figure(1))
        end
        
        function SubtractMeanNoisefromRadioactivityData(app,processeddata,noiseinterval)
            if length(noiseinterval)==2
                tempnoisestartpoint=noiseinterval(1);
                tempnoiseendpoint=noiseinterval(2);
                
                noisestartpoint=app.GetClosestIndexinVector(processeddata,tempnoisestartpoint);
                noiseendpoint=app.GetClosestIndexinVector(processeddata,tempnoiseendpoint);
                
                meannoise=mean(abs(processeddata.tic.values((noisestartpoint:noiseendpoint))));
                
                %Subtract noise from all radioactivity values;update to Map
                mapkeys=keys(app.RadiotracerdataMap);
                for i=1:length(mapkeys)
                    chromatogramfile=mapkeys{i};
                    tracerdata=app.RadiotracerdataMap(chromatogramfile);
                    tracerdata.tic.values=tracerdata.tic.values-meannoise;
                    
                    %Artificially 0 out any chromatgram values less than 0
                    tracerdata.tic.values(tracerdata.tic.values<0)=0;
                    app.RadiotracerdataMap(chromatogramfile)=tracerdata;
                end
                
            else
                f = errordlg('Choose two points (two clicks) for the definition of the noise interval. ');
                return
            end
            
        end
        
        function closestindex=GetClosestIndexinVector(app,processeddata,peakintervals)
                chosenindex=peakintervals;
                [ ~, index] = min(abs(processeddata.time*60-chosenindex));
                closestindex=find(processeddata.time==processeddata.time(index));
        end
                
        function GetChromatogramPeaks(app)
            chromatogramfile=app.ListBox.Value;
            isValiddata=contains(chromatogramfile,'Item');
            if ~isValiddata
                tracerdata=app.RadiotracerdataMap(chromatogramfile);
                peakintervals=app.SelectInterval(tracerdata);
                if ~isempty(peakintervals)
                    %Error check user input
                    if mod(length(peakintervals),2)~=0
                        close all
                        f = errordlg('Uneven amount of endpoints specified. Please redo analysis and make sure to click an even amount of endpoints (ie 2 endpoints per peak)');
                        return
                    end
                    
                    peakstartpoints=peakintervals(1:2:end)';
                    peakendpoints= peakintervals(2:2:end)';
                    
                    mapkeys=keys(app.RadiotracerdataMap);
                    for i=1:length(mapkeys)
                        chromatogramfile=mapkeys{i};
                        tracerdata=app.RadiotracerdataMap(chromatogramfile);
                        
                        %Iterate through all peaks defined per chromatogram,
                        %and store into map
                        arrayofpeakstructs=struct('start',{},'end',{});
                        for j=1:length(peakstartpoints)
                            startofinterval=app.GetClosestIndexinVector(tracerdata,peakstartpoints(j));
                            endofinterval=app.GetClosestIndexinVector(tracerdata,peakendpoints(j));
                            
                            peak.start=startofinterval;
                            peak.end=endofinterval;
                            
                            arrayofpeakstructs(end+1)=peak;
                        end
                        app.SelectedPeaksMap(chromatogramfile)=arrayofpeakstructs;
                    end
                else
                    f = warndlg('User did not select any peak intervals. Please select manual defined peaks.');
                end
            end
        end
        
        function PlotSelectedChromatogramPeaks(app)
            mapkeys=keys(app.RadiotracerdataMap);
            for i=1:length(mapkeys)
                chromatogramfile=mapkeys{i};
                tracerdata=app.RadiotracerdataMap(chromatogramfile);
                peaksforthischromatogram=app.SelectedPeaksMap(chromatogramfile);
                
                figure(i)
                plot(tracerdata.time*60,tracerdata.tic.values)
                temp = strfind(chromatogramfile,'_')
                chromatogramfile(temp)= '-'
                title(chromatogramfile)
                xlabel('Time (secs)');
                ylabel('Radioactivity');
                %xlim([0,((round(tracerdata.time(end))))*60]);
                
                %TODO: Shade
%                 hold on;
%                 for j=1:length(peaksforthischromatogram)
%                     starttime=peaksforthischromatogram.start;
%                     endtime=peaksforthischromatogram.end
%                     areatohighlight=area(tracerdata.time(starttime:endtime),tracerdata.tic.values(starttime:endtime))
%                 end
            end
            
            
        end
        
        function DisplayPeakIntervalTimes(app)
            %We only need to pull out the peak time intervals for one
            %chromatogram, since the time intervals are consistent for all
            %chromatograms
            mapkeys=keys(app.RadiotracerdataMap);
            chromatogramfile=mapkeys{1};
            tracerdata=app.RadiotracerdataMap(chromatogramfile);
            peaksforthischromatogram=app.SelectedPeaksMap(chromatogramfile);
            for i=1:length(peaksforthischromatogram)
                starttime=peaksforthischromatogram(i).start;
                endtime=peaksforthischromatogram(i).end;
                boxtext=sprintf('Peak %i: %f-%f',i, tracerdata.time(starttime)*60,tracerdata.time(endtime)*60);
                app.ListBox_2.Items{i}=boxtext
            end
        end
        
        function ManualPeakTimeIntervalEdit(app)
            chromatogramfile=app.ListBox.Value;
            isValiddata=contains(chromatogramfile,'Item');
            if ~isValiddata
                tracerdata=app.RadiotracerdataMap(chromatogramfile);
                peakintervals=app.SelectInterval(tracerdata);
                if ~isempty(peakintervals)
                    %Error check user input
                    if mod(length(peakintervals),2)~=0
                        close all
                        f = errordlg('Uneven amount of endpoints specified. Please redo analysis and make sure to click an even amount of endpoints (ie 2 endpoints per peak)');
                        return
                    end
                    
                    peakstartpoints=peakintervals(1:2:end)';
                    peakendpoints= peakintervals(2:2:end)';
                    
                    arrayofpeakstructs=struct('start',{},'end',{});
                    for j=1:length(peakstartpoints)
                        startofinterval=app.GetClosestIndexinVector(tracerdata,peakstartpoints(j));
                        endofinterval=app.GetClosestIndexinVector(tracerdata,peakendpoints(j));
                        
                        peak.start=startofinterval;
                        peak.end=endofinterval;
                        
                        arrayofpeakstructs(end+1)=peak;
                    end
                    
                    %Overwrite old peak interval data
                    app.SelectedPeaksMap(chromatogramfile)=arrayofpeakstructs;
                    
                end
                
            end
            
        
        end
        
        
        function PlotChromatogramsin3d(app)
            
            figure();
            xlabel('Time (secs)');
            ylabel('Frame Time Cross Section (mins)');
            zlabel('Parent Fraction (Unitless)');
            hold on;
            
            mapkeys=keys(app.RadiotracerdataMap);
            for i=1:length(mapkeys)
                chromatogramfile=mapkeys{i};
                tracerdata=app.RadiotracerdataMap(chromatogramfile);
                timeofchromatogramsample=app.ExtractChromatogramTimeFromFileName(chromatogramfile);
                if timeofchromatogramsample~=-1
                    timeofdatacollection=tracerdata.time;
                    timeofchromatogram=[ones(1,length(x))*timeofchromatogramsample]';
                    values=tracerdata.tic.values;
                    maxofvalues=max(values);
                    stem3(timeofdatacollection,timeofchromatogram,values/maxofvalues,'Color',[0.5 0.5 0.5],'Marker','.','Markersize',1);
                else
                    errordlg=('Could not properly extract timing information from chromatogram folder name. Please recheck naming convention.')
                    return
                end
                
            end


%             a=app.ListBox.Items{1}
%             testcond=strcmp(a,'Items 1')
%             if app.selectpeaks==1
%                 if testcond==0  %Make sure there is data in the listbox
%                     %                     for Z=1:app.tempj
%                     %                         input=strcat(app.path,'/',app.ListBox.Items{Z});
%                     %                     end
%                     figure(20);
%                     title(app.path);
%                     xlabel('Time (secs)');
%                     ylabel('Frame Time Cross Section (mins)');
%                     zlabel('Parent Fraction (Unitless)');
%                     test=zeros(1,length(app.datatemp));
%                     for i=1:length(app.datatemp)
%                         test(i)=length(app.datatemp{i}.tic.values);
%                     end
%                     mnm=min(test);
%                     hold on
%                     for Z=1:app.tempj
%                         
%                         x=app.datatemp{Z}.time(1:mnm);
%                         ztemp=length(x);
%                         dtime=app.dummy{Z,1}/60;
%                         y=ones(ztemp,1)*dtime;
%                         z=app.datatemp{Z}.tic.values(1:mnm);
%                         zmax=max(z);
%                         z=z/zmax;
%                         hold on
%                         stem3(x,y,z,'Color',[0.5 0.5 0.5],'Marker','.','Markersize',1);
%                         view([18.8 10.8]);
%                         grid('on');
%                         
%                         for i=1:(length(app.storeindAnewcust(Z,:)))
%                             xnew=app.datatemp{Z}.time((app.storeindAnewcust(Z,i)):(app.storeindBnewcust(Z,i)));
%                             ztemp=length(xnew);
%                             ynew=ones(ztemp,1)*dtime;
%                             znewtemp=app.datatemp{Z}.tic.values((app.storeindAnewcust(Z,i)):(app.storeindBnewcust(Z,i))); %Standardize plots
%                             znew=znewtemp/zmax;
%                             stem3(xnew,ynew,znew,'Markersize',1)
%                         end
%                     end
%                     view([18.8 10.8]);
%                     grid('on');
%                 end
%             end
            
        end
        
        function extractedtime=ExtractChromatogramTimeFromFileName(app,filename)
            patterntosearch='[0-9]+( *)?(M|m)(I|i)(N|n)(ute|UTE|utes|UTES)?';
            match=regexp(filename,patterntosearch,"match");
            if ~isempty(match)
                extractedtimecell=regexp(match{1},'[0-9]+',"match");
                extractedtime=str2num(extractedtimecell{1});
            else
                extractedtime=-1;
                return
            end
        
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.initializetoolbox=Chromatography();
        end

        % Button pushed function: LoadFilesButton
        function LoadFilesButtonPushed(app, event)
            agilentfolders=uipickfiles
            
            %Upload folder/agilentdata key-value pairs into Map
            if length(agilentfolders)>0
                for i=1:length(agilentfolders)
                    foldername=agilentfolders{i};
                    [~,chromatogramfile,~] = fileparts(foldername); %Store only the folder name, not full path
                    isValidfoldername=contains(foldername,'.D');
                    if isValidfoldername
                        importdatastruct=app.initializetoolbox.import(foldername);
                        if ~isempty(importdatastruct.name)
                            %store unprocessed chromatogram data
                            app.UnprocessedRadiotracerdataMap(chromatogramfile)=importdatastruct;
                            app.RadiotracerdataMap(chromatogramfile)=importdatastruct;
                            app.PreprocessRadiotracer()
                        end
                    end
                end
            else
                %no folders selected
                return
            end
            
            %Display files to user via listbox
            app.DisplayChromatogramFileNames();
            
            
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            app.PlotChromatogramData();
        end

        % Button pushed function: NoiseButton
        function NoiseButtonPushed(app, event)
            app.ApplyNoiseReduction();
        end

        % Button pushed function: SelectPeaksButton
        function SelectPeaksButtonPushed(app, event)
            app.GetChromatogramPeaks();
            app.PlotSelectedChromatogramPeaks();
            app.DisplayPeakIntervalTimes();
        end

        % Button pushed function: ManualEditPeakButton
        function ManualEditPeakButtonPushed(app, event)
            app.ManualPeakTimeIntervalEdit();
        end

        % Button pushed function: Generate3DVisualizationButton
        function Generate3DVisualizationButtonPushed(app, event)
            app.PlotChromatogramsin3d()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Phase1Tab = uitab(app.TabGroup);
            app.Phase1Tab.Title = 'Phase 1';
            app.UIAxes = uiaxes(app.Phase1Tab);
            title(app.UIAxes, 'Chromatogram Data')
            xlabel(app.UIAxes, 'Time (secs)')
            ylabel(app.UIAxes, 'Radioactivity')
            app.UIAxes_2 = uiaxes(app.Phase1Tab);
            title(app.UIAxes_2, 'Parent Fraction')
            xlabel(app.UIAxes_2, 'Time (mins)')
            ylabel(app.UIAxes_2, 'Parent Fraction (Unitless)')
            app.Step1Label = uilabel(app.Phase1Tab);
            app.Step1Label.Text = 'Step 1:';
            app.ChooseDataTypeLabel = uilabel(app.Phase1Tab);
            app.ChooseDataTypeLabel.Text = 'Choose Data Type';
            app.DataType = uidropdown(app.Phase1Tab);
            app.DataType.Items = {'C-11', 'F-18'};
            app.DataType.Value = 'C-11';
            app.Step2Label = uilabel(app.Phase1Tab);
            app.Step2Label.Text = 'Step 2:';
            app.LoadFilesButton = uibutton(app.Phase1Tab, 'push');
            app.LoadFilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFilesButtonPushed, true);
            app.LoadFilesButton.Text = 'Load Files';
            app.Step7Label = uilabel(app.Phase1Tab);
            app.Step7Label.Text = 'Step 7:';
            app.SelectPeaksButton = uibutton(app.Phase1Tab, 'push');
            app.SelectPeaksButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPeaksButtonPushed, true);
            app.SelectPeaksButton.Text = 'Select Peaks';
            app.ListBox = uilistbox(app.Phase1Tab);
            app.ListBox.Items = {'Item 1', 'Item 2', 'Item 3', 'Item 4', 'Item 5', 'Item 6', 'Item 7', 'Item 8', 'Item 9', 'Item 10'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.OptionalStep4aADVANCEDLabel = uilabel(app.Phase1Tab);
            app.OptionalStep4aADVANCEDLabel.Text = 'Optional* Step 4a (ADVANCED):';
            app.ManualEditPeakButton = uibutton(app.Phase1Tab, 'push');
            app.ManualEditPeakButton.ButtonPushedFcn = createCallbackFcn(app, @ManualEditPeakButtonPushed, true);
            app.ManualEditPeakButton.Text = 'Manual Edit Peak';
            app.Step3HighlightChromatogramFoldertouseforPeakSelectionLabel = uilabel(app.Phase1Tab);
            app.Step3HighlightChromatogramFoldertouseforPeakSelectionLabel.Text = 'Step 3: Highlight Chromatogram Folder to use for Peak Selection   ';
            app.Generate3DVisualizationButton = uibutton(app.Phase1Tab, 'push');
            app.Generate3DVisualizationButton.ButtonPushedFcn = createCallbackFcn(app, @Generate3DVisualizationButtonPushed, true);
            app.Generate3DVisualizationButton.Text = 'Generate 3D Visualization';
            app.Step4Label = uilabel(app.Phase1Tab);
            app.Step4Label.Text = 'Step 4:';
            app.ChooseTimeIntervalthatRepresentsParentPeakLabel = uilabel(app.Phase1Tab);
            app.ChooseTimeIntervalthatRepresentsParentPeakLabel.Text = 'Choose Time Interval that Represents Parent Peak ';
            app.ResetButton = uibutton(app.Phase1Tab, 'push');
            app.ResetButton.Text = 'Reset';
            app.CloseAppButton = uibutton(app.Phase1Tab, 'push');
            app.CloseAppButton.Text = 'Close App';
            app.ListBox_2 = uilistbox(app.Phase1Tab);
            app.ListBox_2.Items = {'Item 1', 'Item 2', 'Item 3', 'Item 4', 'Item 5', 'Item 6', 'Item 7', 'Item 8', 'Item 9', 'Item 10', 'Item 11', 'Item 12', 'Item 13', 'Item 14', 'Item 15'};
            app.Step6Label = uilabel(app.Phase1Tab);
            app.Step6Label.Text = 'Step 6:';
            app.ExportDataButton = uibutton(app.Phase1Tab, 'push');
            app.ExportDataButton.Text = 'Export Data';
            app.Step5Label = uilabel(app.Phase1Tab);
            app.Step5Label.Text = 'Step 5:';
            app.GenerateParentFractionButton = uibutton(app.Phase1Tab, 'push');
            app.GenerateParentFractionButton.Text = 'Generate Parent Fraction';
            app.AsymmetricParameterEditFieldLabel = uilabel(app.Phase1Tab);
            app.AsymmetricParameterEditFieldLabel.Text = 'Asymmetric Parameter: ';
            app.AsymmetricParameterEditField = uieditfield(app.Phase1Tab, 'numeric');
            app.AsymmetricParameterEditField.Limits = [0 1];
            app.AsymmetricParameterEditField.Value = 0.9;
            app.SmoothnessParameterEditFieldLabel = uilabel(app.Phase1Tab);
            app.SmoothnessParameterEditFieldLabel.Text = 'Smoothness Parameter: ';
            app.SmoothnessParameterEditField = uieditfield(app.Phase1Tab, 'numeric');
            app.SmoothnessParameterEditField.Limits = [0 10000];
            app.SmoothnessParameterEditField.Value = 1000;
            app.OPTIONALStep2aADVANCEDLabel = uilabel(app.Phase1Tab);
            app.OPTIONALStep2aADVANCEDLabel.Text = 'OPTIONAL* Step 2a (ADVANCED):  ';
            app.Step8Label = uilabel(app.Phase1Tab);
            app.Step8Label.Text = 'Step 8:';
            app.TextArea = uitextarea(app.Phase1Tab);
            app.TextArea.Value = {'[0,1]'; ''};
            app.TextArea_2 = uitextarea(app.Phase1Tab);
            app.TextArea_2.Value = {'[0,10000]'; ''};
            app.AdjustParametersCheckBox = uicheckbox(app.Phase1Tab);
            app.AdjustParametersCheckBox.Text = 'Adjust Parameters';
            app.CloseFiguresButton = uibutton(app.Phase1Tab, 'push');
            app.CloseFiguresButton.Text = 'Close Figures';
            app.TextArea_3 = uitextarea(app.Phase1Tab);
            app.TextArea_3.Value = {'Smoothness Parameter: 1000'; ''};
            app.TextArea_4 = uitextarea(app.Phase1Tab);
            app.TextArea_4.Value = {'Asymmetric Parameter: 0.9'; ''; ''};
            app.NoiseButton = uibutton(app.Phase1Tab, 'push');
            app.NoiseButton.ButtonPushedFcn = createCallbackFcn(app, @NoiseButtonPushed, true);
            app.NoiseButton.Text = 'Noise';
            app.ApplyCheckBox = uicheckbox(app.Phase1Tab);
            app.ApplyCheckBox.Text = 'Apply';
            app.OPTIONALStep3aADVANCEDLabel = uilabel(app.Phase1Tab);
            app.OPTIONALStep3aADVANCEDLabel.Text = 'OPTIONAL* Step 3a (ADVANCED):  ';
            app.ListBox_3 = uilistbox(app.Phase1Tab);
            app.ListBox_3.Items = {'Item 1', 'Item 2', 'Item 3', 'Item 4', 'Item 5', 'Item 6', 'Item 7', 'Item 8', 'Item 9', 'Item 10'};
            app.Phase2Tab = uitab(app.TabGroup);
            app.Phase2Tab.Title = 'Phase 2';
            app.UIAxes3 = uiaxes(app.Phase2Tab);
            title(app.UIAxes3, 'Plasma Total Metabolite Corrected')
            xlabel(app.UIAxes3, 'Time (mins)')
            ylabel(app.UIAxes3, 'Radioactivity')
            app.UncorrectedPlasmaActivityButton = uibutton(app.Phase2Tab, 'push');
            app.UncorrectedPlasmaActivityButton.Text = 'Uncorrected Plasma Activity';
            app.EditField = uieditfield(app.Phase2Tab, 'text');
            app.EditField.Value = '(Assumed input time is in seconds)';
            app.InputParentFractiontxtpathButton = uibutton(app.Phase2Tab, 'push');
            app.InputParentFractiontxtpathButton.Text = 'Input "ParentFraction.txt" path';
            app.EditField_2 = uieditfield(app.Phase2Tab, 'text');
            app.EditField_2.Value = '(Assumed input time is in minutes)';
            app.EditFieldPMOD = uieditfield(app.Phase2Tab, 'text');
            app.ClearButton = uibutton(app.Phase2Tab, 'push');
            app.ClearButton.Text = 'Clear';
            app.GenerateCombinedAnalysisButton = uibutton(app.Phase2Tab, 'push');
            app.GenerateCombinedAnalysisButton.Text = 'Generate Combined Analysis';
            app.Step1InputUncorrectedPlasmaBldFilePathsLabel = uilabel(app.Phase2Tab);
            app.Step1InputUncorrectedPlasmaBldFilePathsLabel.Text = 'Step1: Input Uncorrected Plasma Bld File Path(s) ';
            app.Step2InputParentFractionFilePathtxtorbldLabel = uilabel(app.Phase2Tab);
            app.Step2InputParentFractionFilePathtxtorbldLabel.Text = 'Step2: Input Parent Fraction File Path (.txt or bld) ';
            app.Step3GenerateAnalysisLabel = uilabel(app.Phase2Tab);
            app.Step3GenerateAnalysisLabel.Text = 'Step3: Generate Analysis ';
            app.ListBox_4 = uilistbox(app.Phase2Tab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AgilentHPLC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end